classdef surface2volume < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        Surface2VolumealgorithmLabel  matlab.ui.control.Label
        RunautomationButton           matlab.ui.control.Button
        ThicknessEditField            matlab.ui.control.NumericEditField
        ThicknessEditFieldLabel       matlab.ui.control.Label
        AnglethresholdEditField       matlab.ui.control.NumericEditField
        AnglethresholdEditFieldLabel  matlab.ui.control.Label
        ModelDropDown                 matlab.ui.control.DropDown
        ModelDropDownLabel            matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        thickness   % Thickness of patches to be extruded
        nurbs_surf  % original surface patches
    end
    
    methods (Access = private)
        
        function results = defaultValues(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set default values
            %
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            model = app.ModelDropDown.Value;
            ax = app.UIAxes;
            axis(ax,'off')
            cla(ax)
            switch model
                case 'Mutter'
                    nurbs_vol = read_g2('../IGA-geometries/Mutter/Mutter.g2');
                    nurbs_vol = cleanNURBS(nurbs_vol,[],1e-2);
                    app.nurbs_surf  = extractFreeSurface(nurbs_vol);
            
                    app.ThicknessEditField.Value = 1.1;
                    app.AnglethresholdEditField.Value = 120; % Threshold for a "sharp" angle
                case 'Capablanca'
                    load('../../results/ASIGA/Capablanca/nurbs.mat','nurbs')
                    app.nurbs_surf = nurbs;
            
                    app.ThicknessEditField.Value = 1.1;
                    app.AnglethresholdEditField.Value = 120; % Threshold for a "sharp" angle
                    a = open('../../results/ASIGA/Capablanca/CAD.fig');
                case 'Capablanca_woRooks'
                    load('../../results/ASIGA/Capablanca/nurbs_woRook.mat','nurbs')
                    app.nurbs_surf = nurbs;
                    app.ThicknessEditField.Value = 1.1;
                    app.AnglethresholdEditField.Value = 120; % Threshold for a "sharp" angle
                    a = open('../../results/ASIGA/Capablanca/CADwoRook.fig');
                case 'BCA'
                    load('BeTSSi_BCA_p2_unRefined.mat','nurbs')
                    app.nurbs_surf = nurbs;
                    app.ThicknessEditField.Value = 1.1;
                    % sharpAngle = 100*pi/180; % Threshold for a "sharp" angle
                    app.AnglethresholdEditField.Value = 120; % Threshold for a "sharp" angle
                    % sharpAngle = 125*pi/180; % Threshold for a "sharp" angle
                    % sharpAngle = 135*pi/180; % Threshold for a "sharp" angle
                    % sharpAngle = 160*pi/180; % Threshold for a "sharp" angle
                    % sharpAngle = 161*pi/180; % Threshold for a "sharp" angle % In order to include connections over depth rudders
                    % sharpAngle = 173*pi/180; % Threshold for a "sharp" angle
                    a = open('../../results/ASIGA/BCA/CAD.fig');
                case 'S1_interior'
                    app.nurbs_surf = getEllipsoidData('parm',2,'S2V_algorithm',{'A1_2'});
                    app.nurbs_surf = flipNURBSparametrization(app.nurbs_surf,1);
                    app.AnglethresholdEditField.Value = 140*pi/180; % Threshold for a "sharp" angle
                case 'S1'
                    app.nurbs_surf = getEllipsoidData('parm',1);
                    app.AnglethresholdEditField.Value = 140*pi/180; % Threshold for a "sharp" angle
            end
            if exist('a','var')
                blah_items = get(gca,'Children');
                copyobj(blah_items,ax)
                close(a)
            end
            if ~ismember(model,{'BCA','Capablanca','Capablanca_woRooks'})
                axis(ax, 'equal')
                color = getColor();
                plotNURBSvec(ax,app.nurbs_surf ,'plotControlPolygon',0,'plotParmDir',0,'color',color);
%                 drawnow
                view(ax,getView());
            end
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Value changed function: AnglethresholdEditField
        function AnglethresholdEditFieldValueChanged(app, event)

        end

        % Value changed function: ThicknessEditField
        function ThicknessEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: RunautomationButton
        function RunautomationButtonPushed(app, event)
            geometry = getTopology(nurbs);
            nurbs_vol = surfaceToVolume(nurbs,'t',t,'sharpAngle',sharpAngle);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {268, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'parula');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ModelDropDownLabel = uilabel(app.LeftPanel);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.LeftPanel);
            app.ModelDropDown.Items = {'<Select model>', 'BCA', 'S1', 'S1_interior'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = '<Select model>';
            app.AnglethresholdEditFieldLabel = uilabel(app.LeftPanel);
            app.AnglethresholdEditFieldLabel.Text = 'Angle threshold';
            app.AnglethresholdEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AnglethresholdEditField.ValueChangedFcn = createCallbackFcn(app, @AnglethresholdEditFieldValueChanged, true);
            app.AnglethresholdEditField.Value = 130;
            app.ThicknessEditFieldLabel = uilabel(app.LeftPanel);
            app.ThicknessEditFieldLabel.Text = 'Thickness';
            app.ThicknessEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ThicknessEditField.ValueChangedFcn = createCallbackFcn(app, @ThicknessEditFieldValueChanged, true);
            app.ThicknessEditField.Value = 0.4;
            app.RunautomationButton = uibutton(app.LeftPanel, 'push');
            app.RunautomationButton.ButtonPushedFcn = createCallbackFcn(app, @RunautomationButtonPushed, true);
            app.RunautomationButton.Text = 'Run automation';
            app.Surface2VolumealgorithmLabel = uilabel(app.LeftPanel);
            app.Surface2VolumealgorithmLabel.Text = 'Surface2Volume algorithm';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = surface2volume

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end